classdef scenario_designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ScenarioMenu             matlab.ui.container.Menu
        LoadMenu                 matlab.ui.container.Menu
        SaveMenu                 matlab.ui.container.Menu
        DeleteMenu               matlab.ui.container.Menu
        HelpMenu                 matlab.ui.container.Menu
        GridLayout               matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        OutlineTab               matlab.ui.container.Tab
        ModifyPointsPanel        matlab.ui.container.Panel
        AppendButton             matlab.ui.control.StateButton
        InsertButton             matlab.ui.control.StateButton
        DeletePointButton        matlab.ui.control.StateButton
        MoveButton               matlab.ui.control.StateButton
        ModifyRoadPanel          matlab.ui.container.Panel
        ActiveroadDropDownLabel  matlab.ui.control.Label
        ActiveroadDropDown       matlab.ui.control.DropDown
        DeleteRoadButton         matlab.ui.control.Button
        BoundaryconditionsPanel  matlab.ui.container.Panel
        CyclicCheckBox           matlab.ui.control.CheckBox
        PsiPanel                 matlab.ui.container.Panel
        radLabel                 matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Psi0                     matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        Psi1                     matlab.ui.control.NumericEditField
        AutoCheckBox             matlab.ui.control.CheckBox
        LanelayoutTab            matlab.ui.container.Tab
        GlobalLayoutPanel        matlab.ui.container.Panel
        InsertLaneButton         matlab.ui.control.StateButton
        RemoveLaneButton         matlab.ui.control.StateButton
        OffsetButton             matlab.ui.control.StateButton
        ConnectButton            matlab.ui.control.StateButton
        LocalLayoutPanel         matlab.ui.container.Panel
        WidthButton              matlab.ui.control.StateButton
        GapButton                matlab.ui.control.StateButton
        PropertyButton           matlab.ui.control.StateButton
        PropertyDropDown         matlab.ui.control.DropDown
        ActivePropertyPanel      matlab.ui.container.Panel
        AddTransitionButton      matlab.ui.control.StateButton
        RemoveTransitionButton   matlab.ui.control.StateButton
        ChangeTransitionButton   matlab.ui.control.StateButton
        MoveTransitionButton     matlab.ui.control.StateButton
        TransitionAxes           matlab.ui.control.UIAxes
        ScenarioAxes             matlab.ui.control.UIAxes
    end

    % TODO: ensure that transition value changes that get divided by 2
    % (such as a width change) to calculate a shift, are multiples of
    % 2*LAYOUT_RESOLUTION.
    properties (Access = public)
        ScData      % The data structure describing the currently loaded scenario
        Sc          % The currently loaded scenario
        R           % The road we are currently editing
        L           % The lane we are currently editing
        ChangeData  % Data structure used to store information about the active layout change (including the active property shown in the TransitionAxes)
    end
    
    properties (Access = private)
        MouseDownInfo       % Struct with all information of the last mouse down event
        KeyDownInfo         % Struct with all information of the last key press event
        Scenarios           % All saved scenarios
        ScName              % The name of the scenario that is currently loaded
        RowHeight           % Original row height of the GridLayout
        State               % Struct with all active state information of the app
        
        LAYOUT_CONFIG       % Configuration struct for the different possible layout changes
    end
    
    properties (Access = private,Constant)
        MOUSE_HOLD_DOWN_TIME = 0.4  % After 0.4 seconds we assume the user keeps the mouse button down
        MOUSE_PROXIMITY = 10        % Maximal scaled distance between a point selected by the mouse and a point in the axes for it to be recognized
        LAYOUT_RESOLUTION = 0.1     % Minimal supported resolution for layout changes
    end
    
    methods (Access = private)
        
        %region: UI Menu methods
        function HandleSaveScenario(app,callback,name,confirm)
            if isempty(confirm)
                name = inputdlg("Scenario name:","Save as",1,"NEW_"+app.ScName);
                figure(app.UIFigure);% Refocus
                if ~isempty(name)
                    name = name{1};
                    if isfield(app.Scenarios,name)
                        msg = "Do you want to overwrite this scenario?";
                        title = "Scenario already exists";
                        uiconfirm(app.UIFigure,msg,title,"Icon","warning","Options",["Yes","No"],"CloseFcn",@(src,evt) app.HandleSaveScenario(callback,name,evt.SelectedOptionIndex==1));
                    else
                        % Save the scenario and execute the callback
                        app.SaveScenario(name);
                        callback();
                    end
                end
            elseif ~confirm
                app.HandleSaveScenario(callback); % If the user did not confirm the overwrite, reshow the save dialog
            else
                % If the user did confirm the overwrite, save the scenario
                % and execute the callback
                app.SaveScenario(name);
                callback();
            end
        end
        
        function HandleLoadScenario(app,confirm)
            if ~isequaln(app.ScData,app.Scenarios.(app.ScName))
                % There are unsaved scenario changes
                if isempty(confirm)
                    % Ask for confirmation to discard or save these changes
                    msg = "Do you want to save the currently loaded scenario?";
                    title = "Unsaved scenario changes";
                    uiconfirm(app.UIFigure,msg,title,"Icon","warning","Options",["Yes","No"],"CloseFcn",@(src,evt) app.HandleLoadScenario(evt.SelectedOptionIndex==1));
                elseif confirm
                    % User confirmed to save the currently loaded scenario
                    % first
                    app.HandleSaveScenario(@() app.HandleLoadScenario([]),[],[]);
                else
                    % User does not want to save the currently loaded
                    % scenario first
                    app.LoadScenario([]);
                end
            else
                app.LoadScenario([]);
            end
        end
        
        function HandleDeleteScenario(app,confirm)
            if isempty(confirm)
                % Ask for confirmation to delete this scenario
                msg = "Do you really want to delete the currently loaded scenario? This will permanently remove " + app.ScName + " from the scenarios data file. You cannot undo this action.";
                title = "Confirm removal of scenario";
                uiconfirm(app.UIFigure,msg,title,"Options",["Yes","No"],"CloseFcn",@(src,evt) app.HandleDeleteScenario(evt.SelectedOptionIndex==1));
            elseif confirm
                app.ScData = [];
                app.SaveScenario(app.ScName);
                app.LoadScenario("DEFAULT");
            end
        end
        %endregion
        
        %region: UI Update methods
        function UpdateUI(app)
            app.UIFigure.Name = "Scenario designer (" + app.ScName + ")";
            app.UpdateControls();
            drawnow('limitrate','nocallbacks');
            app.RedrawAxes();
            drawnow();
        end
        
        function UpdateActiveroadDropDown(app)
            nbRoads = numel(app.ScData.roads);
            app.ActiveroadDropDown.Items = [compose("Road %d",1:nbRoads),"New road"];
            app.ActiveroadDropDown.ItemsData = [1:nbRoads,-1];
        end
        
        function UpdateControls(app)
            % State buttons
            outlineBtns = {app.AppendButton,app.InsertButton,app.MoveButton,app.DeletePointButton};
            for btn=outlineBtns
                if ~isequal(app.State.outline,btn{1})
                    % Toggle states of other buttons
                    btn{1}.Value = 0;
                end
            end
            complexTrans = any(strcmp(app.State.change,["insert","remove","connect"]));
            changes = fieldnames(app.LAYOUT_CONFIG);
            for i=1:numel(changes)
                if ~strcmp(app.State.change,changes{i})
                    % Toggle states of other buttons
                    app.LAYOUT_CONFIG.(changes{i}).btn.Value = 0;
                end
                % Disable other layout change buttons if we are in a
                % complex change operation:
                app.LAYOUT_CONFIG.(changes{i}).btn.Enable = ~complexTrans || strcmp(app.State.change,changes{i});
            end
            if numel(app.ScData.roads(app.R).lanes)<=1
                % Disable gap button if the active road has only 1 lane
                app.GapButton.Enable = 0;
            end
            laneData = [app.ScData.roads.lanes];
            if numel([laneData.from])==2*numel(laneData) || numel([laneData.to])==2*numel(laneData)
                % Disable connect button if the environment has no lanes
                % with empty from or to connection properties
                % TODO: does not take inserted/removed lanes into account!
                app.ConnectButton.Enable = 0;
            end
            transBtns = {app.AddTransitionButton,app.RemoveTransitionButton,app.ChangeTransitionButton,app.MoveTransitionButton};
            for btn=transBtns
                % Toggle on the active transition button and toggle of the
                % other transition buttons
                btn{1}.Value = isequal(app.State.trans,btn{1});
            end
            
            % OutlineTab controls
            if size(app.ScData.roads(app.R).outline,1)<=2
                app.DeletePointButton.Value = 0;
                app.DeletePointButton.Enable = 0;
            else
                app.DeletePointButton.Enable = 1;
            end
            
            app.DeleteRoadButton.Enable = numel(app.ScData.roads)>=2;
            
            % Boundary conditions
            app.CyclicCheckBox.Value = isempty(app.ScData.roads(app.R).bc);
            app.AutoCheckBox.Value = any(isnan(app.ScData.roads(app.R).bc));
            app.PsiPanel.Visible = ~app.CyclicCheckBox.Value & ~app.AutoCheckBox.Value;
            if ~app.CyclicCheckBox.Value && ~app.AutoCheckBox.Value
                app.Psi0.Value = app.ScData.roads(app.R).bc(1)/pi;
                app.Psi1.Value = app.ScData.roads(app.R).bc(2)/pi;
            end
            
            % Active transition properties
            if app.TabGroup.SelectedTab == app.OutlineTab || isempty(app.State.change) || strcmp("connect",app.State.change)
                app.TransitionAxes.Visible = 0;
                app.ActivePropertyPanel.Visible = 0;
                app.GridLayout.RowHeight{2} = 0;
                app.ChangeData.P = [];
            else
                app.TransitionAxes.Visible = 1;
                app.ActivePropertyPanel.Visible = 1;
                app.GridLayout.RowHeight{2} = app.RowHeight;
                if isempty(app.ChangeData.cr)
                    app.ChangeData.cr = app.Sc.roads(app.R).getValidity(app.L);% Default to lane validity region
                end
                switch(app.State.change)
                    case "remove"
                        % Find transition responsible for the removal
                        span = [];
                        dir = app.ScData.roads(app.R).lanes(app.L).direction;
                        T = app.ScData.roads(app.R).lanes(app.L).merge;
                        if ~isempty(T)
                            dc = hwsim.Property.combine(app.ScData.roads(app.R).lanes(app.L).offset,app.ScData.roads(app.R).lanes(T).offset, ...
                                                  1,-1,app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION);
                            W = app.ScData.roads(app.R).lanes(app.L).width;
                            for i=1:size(dc,1)
                                if abs(hwsim.Property.evaluate(dc,dc(i,2)))<app.LAYOUT_RESOLUTION && abs(hwsim.Property.evaluate(W,dc(i,2)))>app.LAYOUT_RESOLUTION && ...
                                    (isempty(span) || dir*span(2)>dir*dc(i,2))
                                    % This transition starts sooner than
                                    % the currently saved transition span
                                    span = dc(i,[1,2]);
                                end
                            end
                            app.ChangeData.P = [span,3,0,sign(T-app.L)];
                        else
                            app.ChangeData.P = [];
                        end
                    case "offset"
                        % Global offset is equal to half of the offset sum between
                        % the left edge of the leftmost lane and the right edge of
                        % the rightmost lane:
                        le = hwsim.Property.combine(app.ScData.roads(app.R).lanes(end).offset,app.ScData.roads(app.R).lanes(end).width,1,0.5);
                        re = hwsim.Property.combine(app.ScData.roads(app.R).lanes(1).offset,app.ScData.roads(app.R).lanes(1).width,1,-0.5);
                        app.ChangeData.P = hwsim.Property.combine(le,re,0.5,0.5,app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION);
                    case "gap"
                        % Gap is equal to the difference between the offset
                        % of the right edge of lane N and the left edge
                        % of lane L
                        le = hwsim.Property.combine(app.ScData.roads(app.R).lanes(app.L).offset,app.ScData.roads(app.R).lanes(app.L).width,1,0.5);
                        re = hwsim.Property.combine(app.ScData.roads(app.R).lanes(app.ChangeData.N).offset,app.ScData.roads(app.R).lanes(app.ChangeData.N).width,1,-0.5);
                        app.ChangeData.P = hwsim.Property.combine(re,le,1,-1,app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION);
                    case {"width","height","speed","left","right"}
                        app.ChangeData.P = app.ScData.roads(app.R).lanes(app.L).(app.State.change);
                    case "direction"
                        app.ChangeData.P = [0,0,0,app.ScData.roads(app.R).lanes(app.L).direction*ones(1,2)];
                    case "validity"
                        f = app.ScData.roads(app.R).lanes(app.L).validity{1};
                        t = app.ScData.roads(app.R).lanes(app.L).validity{2};
                        if isempty(f)
                            f = 0;
                        end
                        if isempty(t)
                            t = app.Sc.roads(app.R).length;
                        end
                        app.ChangeData.P = [f,f,0,0,1;t,t,0,0,-1];
                end
                % Transition button behaviour
                if ~isempty(app.State.change) && ~strcmp("connect",app.State.change)
                    app.LAYOUT_CONFIG.(app.State.change).btn.Value = 1;% PropertyButton might get untoggled by other changes associated with it
                    % Enable or disable add and remove transition buttons based
                    % on the bounds specified in LAYOUT_CONFIG:
                    maxN = app.LAYOUT_CONFIG.(app.State.change).max;
                    minN = app.LAYOUT_CONFIG.(app.State.change).min;
                    if ~isempty(maxN) && size(app.ChangeData.P,1)>=maxN
                        app.AddTransitionButton.Enable = 0;
                        if app.State.trans==app.AddTransitionButton
                            app.State.trans = [];
                            app.AddTransitionButton.Value = 0;
                        end
                    else
                        app.AddTransitionButton.Enable = 1;
                    end
                    if ~isempty(minN) && size(app.ChangeData.P,1)<=minN
                        app.RemoveTransitionButton.Enable = 0;
                        if app.State.trans==app.RemoveTransitionButton
                            app.State.trans = [];
                        end
                    else
                        app.RemoveTransitionButton.Enable = 1;
                    end
                    % Change transition allowed or not:
                    app.ChangeTransitionButton.Enable = app.LAYOUT_CONFIG.(app.State.change).allowChange;
                end
            end
            app.PropertyDropDown.Enable = app.PropertyButton.Value;
        end
        
        function RedrawAxes(app)
            cla(app.ScenarioAxes);
            cla(app.TransitionAxes);
            activeRoad = app.Sc.roads(app.R);
            if app.TabGroup.SelectedTab == app.OutlineTab
                app.Sc.plot(app.ScenarioAxes,"Flatten","on","Lanes","off","OutlineColor",[0.6,0.6,0.6],"OutlineMarkers","on");
                activeRoad.plot(app.ScenarioAxes,"Flatten","on","Lanes","off","OutlineColor",[0.635,0.078,0.184],"OutlineMarkers","on");
            elseif app.TabGroup.SelectedTab == app.LanelayoutTab
                app.Sc.plot(app.ScenarioAxes);
                activeRoad.plot(app.ScenarioAxes,"BoundaryColor",[0.635,0.078,0.184],"LaneHighlight",app.L);
            end
            if ~isempty(app.State.change) && ~strcmp("connect",app.State.change)
                s_vals = 0:app.LAYOUT_RESOLUTION:activeRoad.length;
                line(app.TransitionAxes,s_vals,hwsim.Property.evaluate(app.ChangeData.P,s_vals));
                for t=1:size(app.ChangeData.P,1)
                    cp = app.ChangeData.P(t,[1,2]);
                    if min(cp)<=app.ChangeData.cr(2) && max(cp)>=app.ChangeData.cr(1)
                        tc = min(cp):app.LAYOUT_RESOLUTION:max(cp);% Transition control region
                        line(app.TransitionAxes,tc,hwsim.Property.evaluate(app.ChangeData.P,tc),"LineWidth",2,"MarkerIndices",[1,numel(tc)],"Marker","d");
                    end
                end
                % Plot help lines for lower and/or upper bounds:
                if ~isempty(app.LAYOUT_CONFIG.(app.State.change).lb)
                    line(app.TransitionAxes,s_vals([1,end]),[app.LAYOUT_CONFIG.(app.State.change).lb,app.LAYOUT_CONFIG.(app.State.change).lb],"LineStyle","--","Color","k");
                end
                if ~isempty(app.LAYOUT_CONFIG.(app.State.change).ub)
                    line(app.TransitionAxes,s_vals([1,end]),[app.LAYOUT_CONFIG.(app.State.change).ub,app.LAYOUT_CONFIG.(app.State.change).ub],"LineStyle","--","Color","k");
                end
                % Plot help lines for active property's control range:
                line(app.TransitionAxes,[1,1]*app.ChangeData.cr(1),app.TransitionAxes.YLim,"LineStyle","--","Color",[0.4660 0.6740 0.1880]);
                line(app.TransitionAxes,[1,1]*app.ChangeData.cr(2),app.TransitionAxes.YLim,"LineStyle","--","Color",[0.4660 0.6740 0.1880]);
            end
        end
        %endregion
        
        function LoadScenario(app,scenarioName)
            if isempty(scenarioName)
                msg = "Select which scenario to load";
                title = "Load scenario";
                names = fieldnames(app.Scenarios);
                idx = listdlg("PromptString",msg,"Name",title,"SelectionMode","single","ListString",names,"ListSize",[250 200]);
                figure(app.UIFigure);% Refocus
                if ~isempty(idx)
                    scenarioName = names{idx};
                else
                    return;
                end
            end
            
            if isfield(app.Scenarios,scenarioName)
                app.ScName = scenarioName;
                app.ScData = app.Scenarios.(scenarioName);
                app.Sc = hwsim.Scenario(app.ScData);
                app.R = 1;
                app.L = 1;
                app.UpdateActiveroadDropDown();
                app.ActiveroadDropDown.Value = app.R;
                app.UpdateUI();
            else
                msg = "Scenario not found";
                title = "Invalid scenario";
                uialert(app.UIFigure,msg,title);
            end
        end
        
        function SaveScenario(app,scenarioName)
            app.Scenarios.(scenarioName) = app.ScData;
            if isempty(app.ScData)
                app.Scenarios = rmfield(app.Scenarios,scenarioName);
            end
            scenarios = app.Scenarios;
            save("scenarios/scenarios.mat","-struct","scenarios");
            app.ScName = scenarioName;
            app.UpdateUI();
        end
        
        %region: Interactive axes functions
        function ax = getMouseAxes(app,mousePosition)
            % axes.InnerPosition encodes the size and position of the
            % axes as [left,bottom,width,height]
            axes = {app.ScenarioAxes,app.TransitionAxes};
            ax = [];
            for i=1:numel(axes)
                axPos = axes{i}.InnerPosition;
                onAx = axPos(1)<mousePosition(1) && mousePosition(1)<axPos(1)+axPos(3);
                onAx = onAx && axPos(2)<mousePosition(2) && mousePosition(2)<axPos(2)+axPos(4);
                if onAx
                    ax = axes{i};
                end
            end
        end
        
        function point = calculateMousePoint(~,ax,z)
            % Interpolate currentPoint values to get the point at the given
            % z coordinate
            cp = ax.CurrentPoint;
            %disp("Current point:");
            %disp(num2str(cp));
            point = (cp(2,1:2)-cp(1,1:2))*(cp(2,3)-z)/(cp(2,3)-cp(1,3))+cp(2,1:2);
            %disp(["Mouse point: ",num2str(point)]);
        end
        %endregion
        
        function OutlineAxesClick(app,point)
            outline = app.ScData.roads(app.R).outline;
            grid = struct('lb',{[],[]},'ub',{[],[]},'res',{app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION});
            % TODO: handle connections
            if app.AppendButton.Value
                % Append point to road outline
                point = app.alignToGrid(grid,point);% Align point to the grid
                [~,I] = app.autoAlign(app.ScenarioAxes,outline(1,:),point);
                if ~isempty(I)
                    % If the point is close to the start of the road, make it cyclic
                    app.ScData.roads(app.R).bc = [];
                else
                    outline = [outline;point];
                end
            elseif app.InsertButton.Value
                % Insert point on the clothoid closest to the cursor
                [p,~,I] = app.Sc.roads(app.R).getClosestPoint(point);
                p = app.alignToGrid(grid,p);% Align point to the grid
                outline = [outline(1:I,:);p;outline(I+1:end,:)];
            elseif app.MoveButton.Value
                % Move the point closest to MouseDownPoint to the point
                % of the cursor
                [~,I] = app.autoAlign(app.ScenarioAxes,outline,app.MouseDownInfo.point);
                if ~isempty(I)
                    if ~isempty(app.KeyDownInfo) && app.KeyDownInfo.ctrl
                        app.KeyDownInfo = [];% Reset key down info as the release might not be processed with the dialog open
                        answer = inputdlg(["X:","Y:"],"Modify point",1,compose("%.1f",outline(I,:)));
                        if ~isempty(answer)
                            point(1:2) = str2double(answer);
                        end
                    end
                    point = app.alignToGrid(grid,point);% Align point to the grid
                    [~,J] = app.autoAlign(app.ScenarioAxes,outline(1,:),point);
                    if I==size(outline,1) && ~isempty(J)
                        % If we are moving the last point and it is close
                        % to the start of the road, make it cyclic
                        app.ScData.roads(app.R).bc = [];
                    else
                        outline(I,:) = point;
                    end
                end
            elseif app.DeletePointButton.Value
                % Remove the nearest road outline point (unless it's really
                % far away)
                [~,I] = app.autoAlign(app.ScenarioAxes,outline,point);
                if ~isempty(I)
                    outline(I,:) = [];
                end
            end
            app.ScData.roads(app.R).outline = outline;
            app.UpdateOutline();
            app.Sc = hwsim.Scenario(app.ScData);
            app.UpdateUI();
        end
        
        %region: Layout change methods
        function HandleLayoutChangeButtonClick(app,change)
            % Reset transition state when layout change state changes
            app.State.trans = [];
            % Based on the state of the button, add or remove the
            % change state
            if app.LAYOUT_CONFIG.(change).btn.Value
                app.State.change = change;
                switch(change)
                    case "insert"
                        app.ChangeData.P = [];
                        % Start with adding a single transition to the
                        % ActiveProperty.
                        app.State.trans = app.AddTransitionButton;
                        % Create backup of scenario data:
                        app.ChangeData.Sc = app.ScData;
                        app.ChangeData.R = app.R;
                        app.ChangeData.L = app.L;
                    case "remove"
                        app.ChangeData.P = [];
                        % Start with adding a single transition to the
                        % ActiveProperty.
                        app.State.trans = app.AddTransitionButton;
                        % Create backup of scenario data:
                        app.ChangeData.Sc = app.ScData;
                        app.ChangeData.R = app.R;
                        app.ChangeData.L = app.L;
                    case "gap"
                        % If the leftmost lane is active, make L-1 active
                        if app.L==numel(app.ScData.roads(app.R).lanes)
                            app.L = app.L-1;
                        end
                        % Select neighbour at first valid s value:
                        val = app.Sc.roads(app.R).getValidity(app.L);
                        ns = app.Sc.roads(app.R).getNeighbourSpans(app.L,1);
                        I = ns(:,2)<=val(1) & val(:,1)<=ns(:,3);
                        app.ChangeData.N = ns(I,1);
                        app.ChangeData.cr = ns(I,2:3);
                end
            elseif ~strcmp("connect",change)
                app.HandleChangeEnd();
            end
            % Special logic for the connect change:
            if strcmp("connect",change)
                if ~isfield(app.ChangeData,"from")
                    uialert(app.UIFigure,"Select all the lanes from which the connecting road should start. Afterwards press the connect button again.","Make connection (start)","Icon","info");
                    app.ChangeData.from = struct("road",app.R,"lanes",app.L);
                    app.LAYOUT_CONFIG.(change).btn.Text = "Connect (F)";
                elseif ~isfield(app.ChangeData,"to")
                    uialert(app.UIFigure,"Select all the lanes to which the connecting road should lead. Afterwards press the connect button again.","Make connection (end)","Icon","info");
                    app.ChangeData.to = struct("road",app.R,"lanes",app.L);
                    app.LAYOUT_CONFIG.(change).btn.Value = 1;% Make sure the connect button stays toggled on
                    app.LAYOUT_CONFIG.(change).btn.Text = "Connect (T)";
                else
                    % Check possibility of connecting the selected lanes:
                    msg = [];
                    if numel(app.ChangeData.from.lanes)~=numel(app.ChangeData.to.lanes)
                        msg = [msg,"The number of selected lanes does not match! Please select the same number of lanes to start and end the connecting road."];
                    else
                        fromLanes = app.ScData.roads(app.ChangeData.from.road).lanes(app.ChangeData.from.lanes);
                        toLanes = app.ScData.roads(app.ChangeData.to.road).lanes(app.ChangeData.to.lanes);
                        dirF = [fromLanes.direction];
                        dirT = [toLanes.direction];
                        dirS = sign(0.5+sum(dirF));% -1 if the majority of lanes is in opposite direction, 1 on ties and with a majority of lanes in road direction
                        dirE = sign(0.5+sum(dirT));
                        if abs(sum(dirF))~=abs(sum(dirT))
                            msg = [msg,compose("The directions of the lanes in the from selection does not match with the directions of the lanes in the to selection.")];
                        else
                            FT = ["from","to"];
                            FTmsg = ["already has an incoming connection from","is already connected to"];
                            for i=1:numel(fromLanes)
                                if ~isempty(fromLanes(i).(FT(1+(1+dirF(i)*dirS)/2)))
                                    msg = [msg,compose("Lane %d of road %d (number %d of the from selection) %s another lane.",app.ChangeData.from.lanes(i),app.ChangeData.from.road,i,FTmsg(1+(1+dirF(i)*dirS)/2))];
                                end
                                if ~isempty(toLanes(i).(FT(1+(1-dirT(i)*dirE)/2)))
                                    msg = [msg,compose("Lane %d of road %d (number %d of the to selection) %s another lane.",app.ChangeData.to.lanes(i),app.ChangeData.to.road,i,FTmsg(1+(1-dirT(i)*dirE)/2))];
                                end
                            end
                        end
                    end
                    app.LAYOUT_CONFIG.(change).btn.Text = "Connect";
                    if ~isempty(msg)
                        uialert(app.UIFigure,msg,"Lane selection error","Icon","error");
                    else
                        % Update the layout definition based on the selected
                        % lanes:
                        app.UpdateLayout();
                    end
                    % Toggle the connect change off:
                    app.HandleChangeEnd();
                    app.L = 1;
                end
            end
            app.UpdateUI();
        end
        
        function HandleTransitionButtonClick(app)
            % For Insert and remove lane, we have some custom
            % transition button behavior:
            if any(strcmp(app.State.change,["insert","remove"])) && app.RemoveTransitionButton.Value
                % Delete the transition without inserting it into
                % the layout of the scenario.
                app.InsertLaneButton.Value = 0;
                app.RemoveLaneButton.Value = 0;
                app.ChangeData.P = [];
                app.UpdateLayout();% Reset the layout
                app.HandleLayoutChangeButtonClick(app.State.change);% Make sure we properly reset all states and update the UI
            else
                app.UpdateUI();
            end
        end
        
        function HandleLayoutChangeAxesClick(app,ax,point)
            if ax==app.ScenarioAxes && ~isempty(app.KeyDownInfo) && app.KeyDownInfo.alt
                % Find nearest road and make that road active
                distances = nan(1,numel(app.ScData.roads));
                s_vals = nan(size(distances));
                for i=1:numel(distances)
                    [po,s] = app.Sc.roads(i).getClosestPoint(point);
                    psi = app.Sc.roads(i).heading(s,[]);
                    delta = po-point;
                    distances(i) = sqrt(sum(delta.*delta))*sign(sum(delta.*[sin(psi),-cos(psi)]));% Signed distance, positive to the left of the road, negative to the right
                    s_vals(i) = s;
                end
                [~,app.R] = min(abs(distances));
                % Find nearest lane center and make that lane active
                lc = app.Sc.roads(app.R).evalProp('offset',s_vals(app.R));
                [~,newL] = min(abs(lc-distances(app.R)));
                if isempty(app.State.change)
                    app.L = newL;
                elseif strcmp("insert",app.State.change)
                    % TODO: handle case when selected lane changes
                    % before lane is inserted:
%                         if newL>app.ChangeData.L+app.ChangeData.P(5)
%                             app.L = newL-1;
%                         elseif newL<app.ChangeData.L+app.ChangeData.P(5)
%                             app.L = newL;
%                         end
                    app.L = newL;
                    app.ChangeData.R = app.R;
                    app.ChangeData.L = app.L;
                elseif strcmp("gap",app.State.change) && app.L==newL
                    % Allow the reselection of the same lane, but with a
                    % different neighbour
                    ns = app.Sc.roads(app.R).getNeighbourSpans(app.L,1);
                    I = ns(:,2)<=s_vals(app.R) & s_vals(app.R)<=ns(:,3);
                    app.ChangeData.N = ns(I,1);
                    app.ChangeData.cr = ns(I,2:3);
                elseif strcmp("connect",app.State.change)
                    % Allow the selection of multiple lanes
                    if isfield(app.ChangeData,"to")
                        field = "to";
                    else
                        field = "from";
                    end
                    if app.ChangeData.(field).road~=app.R
                        app.ChangeData.(field).road = app.R;
                        app.ChangeData.(field).lanes = [];
                    end
                    I = find(app.ChangeData.(field).lanes==newL,1);
                    if ~isempty(I)
                        % Remove lane from the selection
                        app.ChangeData.(field).lanes(I) = [];
                    else
                        % Append lane to the selection
                        app.ChangeData.(field).lanes(end+1) = newL;
                    end
                    app.L = app.ChangeData.(field).lanes;
                end
            elseif ax==app.TransitionAxes
                % Calculate transition control regions and a mapping from
                % an index in tcr to a transition id
                tcr = cell(size(app.ChangeData.P,1),1);
                M = []; Ip = cell(size(tcr,1),1);
                for i=1:size(tcr,1)
                    cp = app.ChangeData.P(i,[1,2]);
                    if min(cp)<=app.ChangeData.cr(2) && max(cp)>=app.ChangeData.cr(1)
                        tcr{i} = min(cp):app.LAYOUT_RESOLUTION:max(cp);
                        Ip{i} = [numel(M)+1,numel(M)+size(tcr{i},2)];
                        if cp(1)>cp(2)
                            Ip{i}([1,2]) = Ip{i}([2,1]);
                        end
                        M = cat(1,M,i*ones(size(tcr{i},2),1));
                        tcr{i} = [tcr{i}',hwsim.Property.evaluate(app.ChangeData.P,tcr{i})];
                    end
                end
                tcr = cat(1,tcr{:});
                Ip = cat(1,Ip{:});
                % Align mouse point to the the grid defined in LAYOUT_CONFIG
                lb = {0,app.LAYOUT_CONFIG.(app.State.change).lb};
                s_ub = floor(app.Sc.roads(app.R).length/app.LAYOUT_RESOLUTION)*app.LAYOUT_RESOLUTION;
                ub = {s_ub,app.LAYOUT_CONFIG.(app.State.change).ub};
                res = {app.LAYOUT_RESOLUTION,app.LAYOUT_CONFIG.(app.State.change).res};
                grid = struct('lb',lb,'ub',ub,'res',res);
                point = app.alignToGrid(grid,point);
                T = [];
                if app.AddTransitionButton.Value
                    % Add new transition at the mouse point, if allowed
                    s0 = max(0,point(1)-app.LAYOUT_CONFIG.(app.State.change).new_span/2);
                    s1 = min(app.Sc.roads(app.R).length,point(1)+app.LAYOUT_CONFIG.(app.State.change).new_span/2);
                    % Make sure grid bounds are respected after adding this
                    % new transition:
                    diff = app.LAYOUT_CONFIG.(app.State.change).diff;
                    v = hwsim.Property.evaluate(app.ChangeData.P,s1);
                    if ~isempty(ub{2}) && v+diff>ub{2}
                        if v>=ub{2}
                            diff = -diff;
                        else
                            diff = ub{2}-v;
                        end
                    end
                    if ~isempty(lb{2}) && v+diff<lb{2}
                        if v<=lb{2}
                            diff = 0;
                        else
                            diff = lb{2}-v;
                        end
                    end
                    app.ChangeData.P(end+1,:) = [s0,s1,app.LAYOUT_CONFIG.(app.State.change).new_type,0,diff];
                    T = size(app.ChangeData.P,1);
                    % Switch s0 and s1 values if the lane is in the
                    % opposite direction
                    if app.ScData.roads(app.R).lanes(app.L).direction<0
                        app.ChangeData.P(end,[1,2]) = app.ChangeData.P(end,[2,1]);
                    end
                elseif app.RemoveTransitionButton.Value
                    % Remove the nearest transition (unless it's really far
                    % away). The first (constant) transition cannot be
                    % removed.
                    [~,I] = app.autoAlign(app.TransitionAxes,tcr,point);
                    if ~isempty(I) && (M(I)~=1 || ~app.LAYOUT_CONFIG.(app.State.change).const)
                        app.ChangeData.P(M(I),:) = [];
                    end
                elseif app.ChangeTransitionButton.Value
                    % Change the transition smoothing of the nearest
                    % transition
                    [~,I] = app.autoAlign(app.TransitionAxes,tcr,point);
                    if ~isempty(I)
                        app.ChangeData.P(M(I),3) = mod(app.ChangeData.P(M(I),3)+1,4);
                    end
                elseif app.MoveTransitionButton.Value
                    T = [];
                    if ~isempty(app.KeyDownInfo) && app.KeyDownInfo.ctrl
                        % Find nearest transition:
                        [~,I] = app.autoAlign(app.TransitionAxes,tcr,point);
                        if ~isempty(I)
                            T = M(I);P = 2;
                            new_trans = app.ChangeData.P(T,:);
                            app.KeyDownInfo = [];% Reset key down info as the release might not be processed with the dialog open
                            % And show a dialog to manually move it
                            answer = inputdlg(["Transition start:","Transition end:","Start value:","End value:"],"Modify transition",1,compose("%.1f",app.ChangeData.P(T,[1,2,4,5])));
                            if ~isempty(answer)
                                new_trans([1,2,4,5]) = str2double(answer);
                            else
                                T = [];
                            end
                        end
                    else
                        % Move the control point closest to MouseDownPoint to
                        % the point of the cursor
                        [~,I] = app.autoAlign(app.TransitionAxes,tcr(Ip(:),:),app.MouseDownInfo.point);
                        if ~isempty(I)
                            % Move the control point closest to MouseDownPoint to the point
                            % of the cursor
                            [~,P] = ind2sub(size(Ip),I);
                            T = M(Ip(I));
                            new_trans = app.ChangeData.P(T,:);
                            % Otherwise use point of the cursor to
                            % determine the new value of the transition
                            new_val = app.ChangeData.P(T,3+P) + point(2) - tcr(Ip(I),2);
                            new_trans([P,3+P]) = [point(1),new_val];
                        end
                    end
                    if ~isempty(T)
                        % Apply restrictions to transition changes:
                        if app.LAYOUT_CONFIG.(app.State.change).const && T==1
                            % First transition should stay constant and
                            % cannot move
                            new_trans = [0,0,app.ChangeData.P(1,3),new_trans(3+P),new_trans(3+P)];
                        elseif P==1
                            % For all other transitions, the start point
                            % cannot change value
                            new_trans(4) = app.ChangeData.P(T,4);
                        end
                        % Switch s0 and s1 values if they do not comply
                        % with the lane direction
                        dir = app.ScData.roads(app.R).lanes(app.L).direction;
                        if new_trans(1)*dir>new_trans(2)*dir
                            new_trans([1,2]) = new_trans([2,1]);
                            P = 3-P;
                        end
                        if app.LAYOUT_CONFIG.(app.State.change).step
                            % For stepwise properties: move both s0 and s1:
                            new_trans(3-P) = new_trans(P);
                        elseif new_trans(1)==new_trans(2)
                            % For other properties, convert step transition
                            % to constant:
                            new_trans(6-P) = new_trans(3+P);
                        end
                        % Update the transition:
                        app.ChangeData.P(T,:) = new_trans;
                    end
                end
                % If a new transition is added, or an existing transition
                % is moved, check that the property bounds are still
                % respected everywhere:
                if ~isempty(T)
                    tT = app.ChangeData.P(T,5);
                    for i=1:size(app.ChangeData.P,1)
                        v = hwsim.Property.evaluate(app.ChangeData.P,app.ChangeData.P(i,2));
                        if ~isempty(ub{2}) && v>ub{2}
                            if v-ub{2}<tT
                                app.ChangeData.P(T,5) = tT-v+ub{2};
                            else
                                app.ChangeData.P(T,5) = -tT;
                                v = v-2*tT;
                                tT = -tT;
                            end
                        end
                        if ~isempty(lb{2}) && v<lb{2}
                            app.ChangeData.P(T,5) = tT-v+lb{2};
                        end
                    end
                end
                % For Insert and remove lane, we have some custom
                % transition button behavior:
                if (app.InsertLaneButton.Value || app.RemoveLaneButton.Value) && app.AddTransitionButton.Value
                    % Automatically switch from the (initial) add to
                    % the move state:
                    app.State.trans = app.MoveTransitionButton;
                end
                % Finally update the scenario data according to the changes
                % in the ActiveTransition
                app.UpdateLayout();
            end
            app.UpdateUI();
        end
        
        function HandleChangeEnd(app)
            app.State.change = [];
            app.ChangeData = struct("P",[],"cr",[]);
        end
        
        function UpdateLaneIds(app,Lr,action)
            % This function modifies all lane properties encoding explicit
            % lane ids larger than or equal to Lr. For action=='insert',
            % all such ids are increased by 1 ; for action=='remove', all
            % such ids are decreased by 1 and ids equal to Lr are removed.
            d = 0;
            if strcmp(action,"insert")
                d=1;
            elseif strcmp(action,"remove")
                d=-1;
            end
            for lane=1:numel(app.ScData.roads(app.R).lanes)
                laneData = app.ScData.roads(app.R).lanes(lane);
                if ~isempty(laneData.from) && laneData.from(1)==app.R && laneData.from(2)>=Lr
                    laneData.from(2) = laneData.from(2)+d;
                    if laneData.from(2)==Lr && d==-1
                        laneData.from = [];
                    end
                end
                if ~isempty(laneData.to) && laneData.to(1)==app.R && laneData.to(2)>=Lr
                    laneData.to(2) = laneData.to(2)+d;
                    if laneData.to(2)==Lr && d==-1
                        laneData.to = [];
                    end
                end
                if ~isempty(laneData.merge) && laneData.merge>=Lr
                    laneData.merge = laneData.merge+d;
                    if laneData.merge==Lr && d==-1
                        laneData.merge = [];
                    end
                end
                app.ScData.roads(app.R).lanes(lane) = laneData;
            end
        end
        
        function UpdateOutline(app,roadId)
            % This function modifies the clothoid representation of the
            % active road's outline based on updated outline centers
            % and/or updated boundary conditions
            if nargin<2
                roadId = app.R;
            end
            S = ClothoidSplineG2();
            S.verbose(false);
            outline = app.ScData.roads(roadId).outline;
            bc = app.ScData.roads(roadId).bc;
            if isempty(bc)
                outline(end+1,:) = outline(1,:);
                SPL = S.buildP2(outline(:,1),outline(:,2));% Cyclic G2 interpolation
            elseif any(isnan(bc))
                SPL = S.buildP4(outline(:,1),outline(:,2));% Minimal curvature G2 interpolation
            else
                SPL = S.buildP1(outline(:,1),outline(:,2),bc(1),bc(2));% G2 interpolation with start and end slope given
            end
            % Get clothoid parameters
            cp = nan(6,SPL.numSegment());% Clothoid parameters
            for i=1:size(cp,2)
                [cp(1,i),cp(2,i),cp(3,i),cp(4,i),cp(5,i),cp(6,i)] = SPL.get(i).getPars();
            end
            app.ScData.roads(roadId).cp = cp;
        end
        
        function UpdateLayout(app)
            % This function modifies the scenario data based on an updated
            % ActiveProperty
            if ~isempty(app.ChangeData.P)
                app.ChangeData.P = hwsim.Property.simplify(app.ChangeData.P,app.LAYOUT_RESOLUTION,app.LAYOUT_CONFIG.(app.State.change).res);
            end
            switch(app.State.change)
                case "insert"
                    app.ScData = app.ChangeData.Sc;
                    app.R = app.ChangeData.R;
                    app.L = app.ChangeData.L;
                    if ~isempty(app.ChangeData.P) && app.ChangeData.P(5)~=0
                        d = app.ChangeData.P(5);
                        dir = app.ScData.roads(app.R).lanes(app.L).direction;
                        % First copy the current lane's layout:
                        app.ScData.roads(app.R).lanes = app.ScData.roads(app.R).lanes([1:app.L,app.L:end]);
                        % Determine new index of the inserted lane and the
                        % currently active lane
                        newL = app.L+max(0,d);
                        app.L = app.L-min(0,d);
                        % Make sure connection and merge properties of the
                        % other lanes are updated accordingly:
                        app.UpdateLaneIds(newL,"insert");
                        % Set offset to match the lane edge everywhere
                        % (other lanes will move away from inserted lane)
                        app.ScData.roads(app.R).lanes(newL).offset = hwsim.Property.combine(app.ScData.roads(app.R).lanes(newL).offset,app.ScData.roads(app.R).lanes(newL).width, ...
                                                                                      1,d/2,app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION);
                        % Match width of the new lane with the
                        % selected lane's width after the transition:
                        W = app.ScData.roads(app.R).lanes(newL).width;
                        s = 0;
                        if dir<0
                            s = app.Sc.roads(app.R).length;
                        end
                        if abs(app.ChangeData.P(2)-s)>=app.LAYOUT_RESOLUTION || abs(app.ChangeData.P(1)-s)>=app.LAYOUT_RESOLUTION
                            % If the lane is not inserted over the whole
                            % span of the road, start with 0 width, add a
                            % transition to increase the width during the
                            % insertion and afterwards match the selected
                            % lane's width:
                            w1 = hwsim.Property.evaluate(W,app.ChangeData.P(2));% Width of active lane at the end of insertion
                            W = hwsim.Property.select(W(2:end,:),1,app.ChangeData.P(2),dir,false);% Only keep non-constant width transitions that start after the lane is inserted
                            W = hwsim.Property.combine([zeros(1,5);app.ChangeData.P(1:3),0,w1],W,1,1,app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION);
                        elseif isempty(app.ScData.roads(app.R).bc)
                            % If the lane is inserted over the whole span
                            % of the road and the road is cyclic, set
                            % cyclic connection of the inserted lane:
                            app.ScData.roads(app.R).lanes(newL).from = [app.R,newL];
                            app.ScData.roads(app.R).lanes(newL).to = [app.R,newL];
                        end
                        app.ScData.roads(app.R).lanes(newL).width = W;
                        % TODO: maybe remove other lane property
                        % transitions from before the insertion?
                        % Set validity of inserted lane (only valid from s0
                        % onwards)
                        app.ScData.roads(app.R).lanes(newL).validity{1+(1-dir)/2} = app.ChangeData.P(1);
                        app.ScData.roads(app.R).lanes(newL).validity{1+(1+dir)/2} = [];
                        % Set merge of inserted lane
                        app.ScData.roads(app.R).lanes(newL).merge = [];
                        % Shift other lane offsets:
                        shift = hwsim.Property.combine(W,[],0.5,0);
                        for lane=1:numel(app.ScData.roads(app.R).lanes)
                            s = sign(lane-newL);% Add or subtract shift from the other lane offsets
                            app.ScData.roads(app.R).lanes(lane).offset = hwsim.Property.combine(app.ScData.roads(app.R).lanes(lane).offset,shift, ...
                                                                                               1,s,app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION);
                        end
                    end
                case "remove"
                    % TODO: remove lane connections
                    app.ScData = app.ChangeData.Sc;
                    app.Sc = hwsim.Scenario(app.ScData);
                    if ~isempty(app.ChangeData.P)
                        dir = app.ScData.roads(app.R).lanes(app.L).direction;
                        % Clip span of ActiveProperty to be within
                        % the lane's validity range:
                        val = app.Sc.roads(app.R).getValidity(app.L);
                        app.ChangeData.P([1,2]) = max(val(1),app.ChangeData.P([1,2]));
                        app.ChangeData.P([1,2]) = min(val(2),app.ChangeData.P([1,2]));
                        d = nan;
                        if app.ChangeData.P(5)~=0
                            if app.ChangeData.P(2)==val(1+(1-dir)/2)
                                % Remove lane
                                app.ScData.roads(app.R).lanes(app.L) = [];
                                app.UpdateLaneIds(app.L,"remove");
                                app.L = min(numel(app.ScData.roads(app.R).lanes),app.L);
                            else
                                % Prevent ActiveProperty(5) to be +-1 if there is
                                % no left/right neighbour available:
                                d = app.ChangeData.P(5);
                                N = app.Sc.roads(app.R).getLaneNeighbours(app.ChangeData.P(2),app.L);% right and left neighbour
                                T = N(1+(1+d*dir)/2);
                                if isnan(T) || dir~=app.ScData.roads(app.R).lanes(T).direction
                                    % First try the opposite direction
                                    d = -d;
                                    app.ChangeData.P(5) = d;
                                    T = N(1+(1+d*dir)/2);
                                end
                                if isnan(T) || dir~=app.ScData.roads(app.R).lanes(T).direction
                                    % If that's also not available, abort the
                                    % removal opertation
                                    d = nan;
                                    app.ChangeData.P = [];
                                end
                            end
                        end
                        if ~isnan(d)
                            % Match width and offset of the target lane after
                            % the remove transition:
                            W0 = app.ScData.roads(app.R).lanes(app.L).width;
                            C0 = app.ScData.roads(app.R).lanes(app.L).offset;
                            W1 = app.ScData.roads(app.R).lanes(T).width;
                            C1 = app.ScData.roads(app.R).lanes(T).offset;
                            % Only keep width and offset transitions that end
                            % before we end merging the lanes and only add
                            % the transitions of the target lane that start
                            % after we start merging the lanes:
                            W = hwsim.Property.combine( ...
                                    hwsim.Property.select(W0(2:end,:),2,app.ChangeData.P(2),-dir,false), ...
                                    hwsim.Property.select(W1(2:end,:),1,app.ChangeData.P(1),dir,false),1,1);
                            C = hwsim.Property.combine( ...
                                    hwsim.Property.select(C0(2:end,:),2,app.ChangeData.P(2),-dir,false), ...
                                    hwsim.Property.select(C1(2:end,:),1,app.ChangeData.P(1),dir,false),1,1);
                            % Keep the constant transition of the original
                            % lane and make sure to remove duplicate transitions
                            % inside the merging region:
                            W = hwsim.Property.combine(W0(1,:),hwsim.Property.removeDuplicates(W),1,1);
                            C = hwsim.Property.combine(C0(1,:),hwsim.Property.removeDuplicates(C),1,1);
                            % Finally, it is possible there are transitions
                            % starting before the merge and ending after
                            % the merge and that are shared between both L
                            % and T. Hence we should keep those as well:
                            W = hwsim.Property.combine(W,hwsim.Property.selectDuplicates( ...
                                                       hwsim.Property.select(W0(2:end,:),2,app.ChangeData.P(2),dir,true), ...
                                                       hwsim.Property.select(W1(2:end,:),1,app.ChangeData.P(1),-dir,true) ...
                                                   ),1,1);
                            C = hwsim.Property.combine(C,hwsim.Property.selectDuplicates( ...
                                                       hwsim.Property.select(C0(2:end,:),2,app.ChangeData.P(2),dir,true), ...
                                                       hwsim.Property.select(C1(2:end,:),1,app.ChangeData.P(1),-dir,true) ...
                                                   ),1,1);
                            % Calculate transitions to match offset and width
                            % of the target lane:
                            dw = hwsim.Property.evaluate(W1,app.ChangeData.P(2))-hwsim.Property.evaluate(W,app.ChangeData.P(2));
                            dc = hwsim.Property.evaluate(C1,app.ChangeData.P(2))-hwsim.Property.evaluate(C,app.ChangeData.P(2));
                            tw = [app.ChangeData.P(1:4),dw];
                            tc = [app.ChangeData.P(1:4),dc];
                            % Update current lane's layout:
                            app.ScData.roads(app.R).lanes(app.L).width = hwsim.Property.combine(W,tw,1,1,app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION);
                            app.ScData.roads(app.R).lanes(app.L).offset = hwsim.Property.combine(C,tc,1,1,app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION);
                            % Calculate shifts based on the updated layout:
                            sw = hwsim.Property.combine(app.ScData.roads(app.R).lanes(app.L).width,W0,1,-1);
                            sc = hwsim.Property.combine(app.ScData.roads(app.R).lanes(app.L).offset,C0,1,-1);
                            % Update other lanes' layout:
                            for lane=1:numel(app.ScData.roads(app.R).lanes)
                                s = sign(lane-app.L+d/2);% Add or subtract shift from the other lane offsets
                                shift = hwsim.Property.combine(sc,sw,-d/2,0.25);
                                app.ScData.roads(app.R).lanes(lane).offset = hwsim.Property.combine(app.ScData.roads(app.R).lanes(lane).offset,shift, ...
                                                                                                   1,s,app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION);
                            end
                            % Set merge property of removed lane:
                            app.ScData.roads(app.R).lanes(app.L).merge = T;
                            % Update lane validity of removed lane group:
                            app.ScData.roads(app.R).lanes(app.L).validity{1+(1+dir)/2} = app.ChangeData.P(2);
                            % Update left and right availability:
                            I = 1+(1+d)/2;% Map d*dir to an index in props array
                            props = ["right","left"];
                            Alm = app.ScData.roads(app.R).lanes(app.L).(props(I));% Merging side of L
                            Atm = app.ScData.roads(app.R).lanes(T).(props(3-I));% Merging side of T
                            Al = hwsim.Property.stepChange(Alm,app.ChangeData.P(1+(1-dir)/2),app.ChangeData.P(1+(1+dir)/2),1);% Enable availability towards target lane during the merge
                            At = hwsim.Property.stepChange(Atm,app.ChangeData.P(1+(1-dir)/2),app.ChangeData.P(1+(1+dir)/2),0);% Disable availability towards the merging lane during the merge
                            % Set new left and right availabilities:
                            app.ScData.roads(app.R).lanes(T).(props(3-I)) = hwsim.Property.simplify(At,app.LAYOUT_RESOLUTION,1);
                            app.ScData.roads(app.R).lanes(app.L).(props(I)) = hwsim.Property.simplify(Al,app.LAYOUT_RESOLUTION,1);
                        end
                    end
                case "offset"
                    le = hwsim.Property.combine(app.ScData.roads(app.R).lanes(end).offset,app.ScData.roads(app.R).lanes(end).width,1,0.5);
                    re = hwsim.Property.combine(app.ScData.roads(app.R).lanes(1).offset,app.ScData.roads(app.R).lanes(1).width,1,-0.5);
                    oldOffset = hwsim.Property.combine(le,re,0.5,0.5);
                    shift = hwsim.Property.combine(app.ChangeData.P,oldOffset,1,-1);
                    for lane=1:numel(app.ScData.roads(app.R).lanes)
                        app.ScData.roads(app.R).lanes(lane).offset = hwsim.Property.combine(app.ScData.roads(app.R).lanes(lane).offset,shift,1,1,app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION);
                    end
                case "connect"
                    roadF = app.ChangeData.from.road;
                    roadT = app.ChangeData.to.road;
                    lanesF = app.ChangeData.from.lanes;
                    lanesT = app.ChangeData.to.lanes;
                    dirF = [app.ScData.roads(roadF).lanes(lanesF).direction];
                    dirT = [app.ScData.roads(roadT).lanes(lanesT).direction];
                    dirS = sign(0.5+sum(dirF));% -1 if the majority of lanes is in opposite direction, 1 on ties and with a majority of lanes in road direction
                    dirE = sign(0.5+sum(dirT));
                    sF = [];
                    sT = [];
                    for i=1:numel(lanesF)
                        val = app.Sc.roads(roadF).getValidity(lanesF(i));
                        if isempty(sF) || dirS*val(1+(1+dirS)/2)<dirS*sF
                            sF=val(1+(1+dirS)/2);
                        end
                        val = app.Sc.roads(roadF).getValidity(lanesT(i));
                        if isempty(sT) || dirE*val(1+(1-dirE)/2)>dirE*sT
                            sT=val(1+(1-dirE)/2);
                        end
                    end
                    % Find lateral offset of the middle of both
                    % lane spans:
                    lcF = app.Sc.roads(roadF).evalProp('offset',sF,lanesF);
                    lcT = app.Sc.roads(roadT).evalProp('offset',sT,lanesT);
                    lF = sum(lcF)/numel(lanesF);
                    lT = sum(lcT)/numel(lanesT);
                    % Sort lanes from rightmost to leftmost (as seen by
                    % the connecting road)
                    [lcF,I] = sort(dirS*(lcF-lF));
                    lanesF = lanesF(I);
                    dirF = dirF(I);
                    [lcT,I] = sort(dirE*(lcT-lT));
                    lanesT = lanesT(I);
                    dirT = dirT(I);
                    % Calculate start and end points of the
                    % connecting road:
                    pF = zeros(1,2);
                    pT = zeros(1,2);
                    [pF(1),pF(2)] = app.Sc.roads(roadF).getGlobalPose(sF,lF,0);
                    [pT(1),pT(2)] = app.Sc.roads(roadT).getGlobalPose(sT,lT,0);
                    if any(abs(pF-pT)>app.LAYOUT_RESOLUTION)
                        % Calculate start and end heading of the
                        % connecting road:
                        hF = app.Sc.roads(roadF).heading(sF,[])+(1-dirS)/2*pi;
                        hT = app.Sc.roads(roadT).heading(sT,[])+(1-dirE)/2*pi;% dirDelta*pi
                        % Add connecting road:
                        app.ScData.roads(end+1).outline = [pF;pT];
                        newR = numel(app.ScData.roads);
                        app.ScData.roads(newR).bc = [hF,hT];
                        app.UpdateOutline(newR);% Calculate clothoid representation of new road
                        D = hwsim.Road(app.ScData.roads(newR)).length;
                        % Get lane properties at start and end points of
                        % the connecting road:
                        lwF = app.Sc.roads(roadF).evalProp('width',sF,lanesF);
                        lwT = app.Sc.roads(roadT).evalProp('width',sT,lanesT);
                        % Add connections to all lane definitions:
                        FT = ["from","to"];
                        for i=1:numel(lanesF)
                            app.ScData.roads(roadF).lanes(lanesF(i)).(FT(1+(1+dirF(i)*dirS)/2)) = [newR,i];
                            app.ScData.roads(roadT).lanes(lanesT(i)).(FT(1+(1-dirT(i)*dirE)/2)) = [newR,i];
                            app.ScData.roads(newR).lanes(i) = app.Scenarios.DEFAULT.roads(1).lanes(1);
                            app.ScData.roads(newR).lanes(i).offset = hwsim.Property.simplify([0,0,0,lcF(i),lcF(i);D/4,3*D/4,3,lcT(i)-lcF(i),lcT(i)-lcF(i)],app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION);
                            app.ScData.roads(newR).lanes(i).width = hwsim.Property.simplify([0,0,0,lwF(i),lwF(i);D/4,3*D/4,3,lwT(i)-lwF(i),lwT(i)-lwF(i)],app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION);
                            app.ScData.roads(newR).lanes(i).direction = dirF(i)*dirS;
                            % TODO: match remaining lane properties at the start and
                            % end of the connecting road?
                            % TODO: and account for possible difference in
                            % lane validity (see calculation of sF and sT)
                            app.ScData.roads(newR).lanes(i).(FT(1+(1-dirF(i)*dirS)/2)) = [roadF,lanesF(i)];
                            app.ScData.roads(newR).lanes(i).(FT(1+(1+dirT(i)*dirE)/2)) = [roadT,lanesT(i)];
                        end
                        % Select first lane of connecting road:
                        app.R = newR;
                        app.L = 1;
                    else
                        % Just add connections between existing lanes of
                        % this road
                        for i=1:numel(lanesF)
                            app.ScData.roads(roadF).lanes(lanesF(i)).to = [roadT,lanesT(i)];
                            app.ScData.roads(roadT).lanes(lanesT(i)).from = [roadF,lanesF(i)];
                        end
                    end
                case "gap"
                    le = hwsim.Property.combine(app.ScData.roads(app.R).lanes(app.L).offset,app.ScData.roads(app.R).lanes(app.L).width,1,0.5);
                    re = hwsim.Property.combine(app.ScData.roads(app.R).lanes(app.ChangeData.N).offset,app.ScData.roads(app.R).lanes(app.ChangeData.N).width,1,-0.5);
                    oldGap = hwsim.Property.combine(re,le,1,-1);
                    shift = hwsim.Property.combine(app.ChangeData.P,oldGap,0.5,-0.5);
                    for lane=1:numel(app.ScData.roads(app.R).lanes)
                        d = sign(lane-app.L-0.5);% Add or subtract shift from the other lane offsets
                        app.ScData.roads(app.R).lanes(lane).offset = hwsim.Property.combine(app.ScData.roads(app.R).lanes(lane).offset,shift, ...
                                                                                           1,d,app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION);
                    end
                case "width"
                    oldWidth = app.ScData.roads(app.R).lanes(app.L).width;
                    app.ScData.roads(app.R).lanes(app.L).width = app.ChangeData.P;
                    shift = hwsim.Property.combine(app.ChangeData.P,oldWidth,0.5,-0.5);
                    for lane=1:numel(app.ScData.roads(app.R).lanes)
                        d = sign(lane-app.L);% Add or subtract shift from the other lane offsets
                        app.ScData.roads(app.R).lanes(lane).offset = hwsim.Property.combine(app.ScData.roads(app.R).lanes(lane).offset,shift, ...
                                                                                           1,d,app.LAYOUT_RESOLUTION,app.LAYOUT_RESOLUTION);
                    end
                    % Remove lane if width is equal to zero everywhere:
                    if all(app.ChangeData.P(:,4)==0) && all(app.ChangeData.P(:,5)==0)
                        app.ScData.roads(app.R).lanes(app.L) = [];
                        app.UpdateLaneIds(app.L,"remove");
                        app.State.change = [];
                    end
                case {"height","speed","left","right"}
                    app.ScData.roads(app.R).lanes(app.L).(app.State.change) = app.ChangeData.P;
                case "direction"
                    app.ScData.roads(app.R).lanes(app.L).direction = app.ChangeData.P(5);
                case "validity"
                    length = app.Sc.roads(app.R).length;
                    f = app.ChangeData.P(1,1);
                    t = app.ChangeData.P(2,1);
                    if abs(length-t)<app.LAYOUT_RESOLUTION
                        t = [];
                    end
                    app.ScData.roads(app.R).lanes(app.L).validity = {f,t};
            end
            app.Sc = hwsim.Scenario(app.ScData);
        end
        %endregion
        
        %region: Utility functions
        function D = getScaledDistance(~,ax,p1,p2)
            S = [ax.XLim(2)-ax.XLim(1),ax.YLim(2)-ax.YLim(1)];
            S = abs(S.*S./[ax.InnerPosition(3)^2,ax.InnerPosition(4)^2]);
            D = pdist2(p1,p2,"mahalanobis",diag(S));
        end
        
        function [p,I] = autoAlign(app,ax,align_points,p)
            % Align p with the given align_points if close enough
            I = [];
            if ~isempty(align_points) && ~isempty(p)
                D = app.getScaledDistance(ax,align_points,p);
                [minD,minI] = min(D);
                %disp(["Minimal distance to align point ",num2str(minI),": ",num2str(minD)]);
                if minD<app.MOUSE_PROXIMITY
                    I = minI;
                    p = align_points(minI,:);
                end
            end
        end
        
        function p = alignToGrid(~,grid,p)
            % Align p to the grid defined by the given lower and
            % upper bounds and resolution
            for i=1:numel(p)
                off = 0;
                if ~isempty(grid(i).lb)
                    p(i) = max(grid(i).lb,p(i));
                    off = mod(grid(i).lb,grid(i).res);
                end
                if ~isempty(grid(i).ub)
                    p(i) = min(grid(i).ub,p(i));
                    off = mod(grid(i).ub,grid(i).res);
                end
                p(i) = round((p(i)+off)/grid(i).res)*grid(i).res-off;
            end
        end
        %endregion
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, scenarioName)
            if nargin<2
                scenarioName = "DEFAULT";
            end
            ROOT = fileparts(mfilename('fullpath'));% Path to folder containing this source file
            addpath(fullfile(ROOT,'../..'));% Make sure we can access the road and scenario classes
            addpath(fullfile(ROOT,'../../../dependencies/Clothoids/matlab'));% Make sure we can acces the Clothoid library
            
            app.RowHeight = app.GridLayout.RowHeight{2};% Save original rowheight value of the LaneAxes row
            % Structure fields description:
            %   * btn:      The UIButton associated with this change
            %   * lb:       Lowerbound of the property values of ActiveProperty (can be empty)
            %   * ub:       Upperbound of the property values of ActiveProperty (can be empty)
            %   * res:      Resolution of the grid for the property values of ActiveProperty
            %   * new_span: Span over which a new transition is added
            %   * diff:     s1 value of a newly added transition
            %   * const:    First row of ActiveProperty should remain constant
            %   * step:     When moving a transition, both s0 and s1 are moved
            %   * allowChange: Enable the transition change button
            %   * min:      Minimum number of transitions for this property (used to disable the remove transition button)
            %   * max:      Maximum number of transitions for this property (used to disable the add transition button)
            app.LAYOUT_CONFIG = struct(...
                "insert",struct("btn",app.InsertLaneButton,"lb",-1,"ub",1,"res",1,"new_span",50,"new_type",3,"diff",1,"const",false,"step",false,"allowChange",false,"min",0,"max",1),...
                "remove",struct("btn",app.RemoveLaneButton,"lb",-1,"ub",1,"res",1,"new_span",50,"new_type",3,"diff",1,"const",false,"step",false,"allowChange",false,"min",0,"max",1),...
                "offset",struct("btn",app.OffsetButton,"lb",[],"ub",[],"res",app.LAYOUT_RESOLUTION,"new_span",50,"new_type",3,"diff",1.8,"const",true,"step",false,"allowChange",true,"min",0,"max",[]),...
                "connect",struct("btn",app.ConnectButton),...
                "width",struct("btn",app.WidthButton,"lb",0,"ub",[],"res",app.LAYOUT_RESOLUTION,"new_span",50,"new_type",3,"diff",0.4,"const",true,"step",false,"allowChange",true,"min",1,"max",[]),...
                "height",struct("btn",app.PropertyButton,"lb",[],"ub",[],"res",app.LAYOUT_RESOLUTION,"new_span",50,"new_type",3,"diff",10,"const",false,"step",false,"allowChange",true,"min",0,"max",[]),...
                "gap",struct("btn",app.GapButton,"lb",0,"ub",[],"res",app.LAYOUT_RESOLUTION,"new_span",50,"new_type",3,"diff",0.4,"const",true,"step",false,"allowChange",true,"min",0,"max",[]),...
                "speed",struct("btn",app.PropertyButton,"lb",0,"ub",[],"res",app.LAYOUT_RESOLUTION,"new_span",50,"new_type",1,"diff",10,"const",true,"step",false,"allowChange",true,"min",0,"max",[]),...
                "direction",struct("btn",app.PropertyButton,"lb",-1,"ub",1,"res",2,"const",true,"step",false,"allowChange",false,"min",1,"max",1),...
                "validity",struct("btn",app.PropertyButton,"lb",0,"ub",1,"res",1,"const",false,"step",true,"allowChange",false,"min",2,"max",2),...
                "left",struct("btn",app.PropertyButton,"lb",0,"ub",1,"res",1,"new_span",0,"new_type",0,"diff",1,"const",false,"step",true,"allowChange",false,"min",0,"max",[]),...
                "right",struct("btn",app.PropertyButton,"lb",0,"ub",1,"res",1,"new_span",0,"new_type",0,"diff",1,"const",false,"step",true,"allowChange",false,"min",0,"max",[])...
            );
            app.State = struct("outline",[],"change",[],"trans",[]);
            app.ChangeData = struct("P",[],"cr",[]);
        
            app.Scenarios = load("scenarios/scenarios.mat");
            drawnow();% Make sure to draw once before modifying the UI, otherwise BIG trouble!
            app.LoadScenario(scenarioName);
            
            app.MouseDownInfo = [];
            app.KeyDownInfo = [];
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            ax = app.getMouseAxes(event.Source.CurrentPoint);
            if ~isempty(ax)
                app.MouseDownInfo = struct('time',cputime(),'ax',ax,'point',app.calculateMousePoint(ax,0));
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if ~isempty(app.MouseDownInfo) && cputime()-app.MouseDownInfo.time>app.MOUSE_HOLD_DOWN_TIME
                % The mouse is being held down and moving around
                if app.TabGroup.SelectedTab==app.OutlineTab
                    % TODO: Move the selected outline point -> might change
                    % the outline too much and not be very user friendly
                end
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if ~isempty(app.MouseDownInfo)
                %disp(['Mouse was held down for ',num2str(cputime()-app.MouseDownInfo.time)]);
                if cputime()-app.MouseDownInfo.time>app.MOUSE_HOLD_DOWN_TIME
                    % Mouse was held down until now
                else
                    % Just a normal mouse click
                end
                if app.TabGroup.SelectedTab==app.OutlineTab
                    app.OutlineAxesClick(app.calculateMousePoint(app.ScenarioAxes,0));
                elseif app.TabGroup.SelectedTab==app.LanelayoutTab
                    app.HandleLayoutChangeAxesClick(app.MouseDownInfo.ax,app.calculateMousePoint(app.MouseDownInfo.ax,0));
                end
                app.MouseDownInfo = [];
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            CtrlDown = any(contains(event.Modifier,'control'));
            AltDown = any(contains(event.Modifier,'alt'));
            ShiftDown = any(contains(event.Modifier,'shift'));
            app.KeyDownInfo = struct('key',event.Key,'ctrl',CtrlDown,'alt',AltDown,'shift',ShiftDown);
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            app.KeyDownInfo = [];
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.HandleSaveScenario([],[],[]);
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            app.HandleLoadScenario([]);
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            app.HandleDeleteScenario([]);
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            msg = "Help is not available";
            title = "Outline/Lanelayout help";
            uialert(app.UIFigure,msg,title,"Icon","info");
        end

        % Value changed function: ActiveroadDropDown
        function ActiveroadDropDownValueChanged(app, event)
            app.R = app.ActiveroadDropDown.Value;
            if app.R<0
                nbRoads = numel(app.ScData.roads);
                app.R = nbRoads+1;
                app.L = 1;
                % Load road from the default scenario
                app.ScData.roads(app.R) = app.Scenarios.DEFAULT.roads(1);
                app.Sc = hwsim.Scenario(app.ScData);
                % Update DropDown items
                app.UpdateActiveroadDropDown();
                app.ActiveroadDropDown.Value = app.R;
            end
            app.UpdateUI();
        end

        % Value changed function: AppendButton, DeletePointButton, 
        % InsertButton, MoveButton
        function ModifyPointsValueChanged(app, event)
            if event.Source.Value
                app.State.outline = event.Source;
            else
                app.State.outline = [];
            end
            app.UpdateUI();
        end

        % Button pushed function: DeleteRoadButton
        function DeleteRoadButtonPushed(app, event)
            app.ScData.roads(app.R) = [];
            app.R = app.R-1;
            % TODO: remove all connections to or from this road
            app.Sc = hwsim.Scenario(app.ScData);
            app.UpdateActiveroadDropDown();
            app.ActiveroadDropDown.Value = app.R;
            app.UpdateUI();
        end

        % Value changed function: AutoCheckBox, CyclicCheckBox
        function BoundaryCheckBoxValueChanged(app, event)
            if event.Source.Value
                if event.Source==app.CyclicCheckBox
                    app.ScData.roads(app.R).bc = [];
                else
                    app.ScData.roads(app.R).bc = [nan,nan];
                end
            else
                app.ScData.roads(app.R).bc = [0,0];
            end
            app.UpdateOutline();
            % Update lane connections when boundary conditions change
            if isempty(app.ScData.roads(app.R).bc)
                app.Sc = hwsim.Scenario(app.ScData);% Make sure UpdateLayout uses the cyclic boundary conditions already!
                app.State.change = "connect";
                app.ChangeData.from = struct("road",app.R,"lanes",[]);
                app.ChangeData.to = struct("road",app.R,"lanes",[]);
                for lane=1:numel(app.ScData.roads(app.R).lanes)
                    f = app.ScData.roads(app.R).lanes(lane).validity{1};
                    t = app.ScData.roads(app.R).lanes(lane).validity{2};
                    if isempty(f) || f==0
                        app.ChangeData.from.lanes(end+1) = lane;
                    end
                    if isempty(t)
                        app.ChangeData.to.lanes(end+1) = lane;
                    end
                end
                app.UpdateLayout();% Inserts the connections and updates Sc
                app.HandleChangeEnd();
            else
                % Remove connections from and to the active road
                for lane=1:numel(app.ScData.roads(app.R).lanes)
                    if app.ScData.roads(app.R).lanes(lane).from(1)==app.R
                        app.ScData.roads(app.R).lanes(lane).from = [];
                    end
                    if app.ScData.roads(app.R).lanes(lane).to(1)==app.R
                        app.ScData.roads(app.R).lanes(lane).to = [];
                    end
                end
                app.Sc = hwsim.Scenario(app.ScData);
            end
            app.UpdateUI();
        end

        % Value changed function: Psi0, Psi1
        function PsiValueChanged(app, event)
            app.ScData.roads(app.R).bc = [app.Psi0.Value*pi,app.Psi1.Value*pi];
            app.UpdateOutline();
            app.Sc = hwsim.Scenario(app.ScData);
            app.UpdateUI();
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if ~isempty(app.State.change)
                % Commit current layout change before changing tabs:
                app.HandleLayoutChangeButtonClick(app.State.change);
            end
            % TODO: drawnow in the following call hangs after lane layout
            % is changed in the layout tab.
            app.UpdateUI();
        end

        % Value changed function: ConnectButton, GapButton, 
        % InsertLaneButton, OffsetButton, PropertyButton, 
        % PropertyDropDown, RemoveLaneButton, WidthButton
        function LayoutChangeButtonValueChanged(app, event)
            change = [];
            if app.PropertyButton.Value && (event.Source==app.PropertyButton || event.Source==app.PropertyDropDown)
                change = app.PropertyDropDown.Value;
            else
                changes = fieldnames(app.LAYOUT_CONFIG);
                for i=1:numel(changes)
                    if event.Source==app.LAYOUT_CONFIG.(changes{i}).btn
                        change = changes{i};
                        break;
                    end
                end
            end
            
            % Handle the layout change
            app.HandleLayoutChangeButtonClick(change);
        end

        % Value changed function: AddTransitionButton, 
        % ChangeTransitionButton, MoveTransitionButton, 
        % RemoveTransitionButton
        function TransitionButtonValueChanged(app, event)
            if event.Source.Value
                app.State.trans = event.Source;
            else
                app.State.trans = [];
            end
            % Handle the transition button click
            app.HandleTransitionButtonClick();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Scenario designer';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.ScenarioMenu = uimenu(app.UIFigure);
            app.ScenarioMenu.Text = 'Scenario';
            app.LoadMenu = uimenu(app.ScenarioMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.ScenarioMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.DeleteMenu = uimenu(app.ScenarioMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            app.HelpMenu = uimenu(app.ScenarioMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Separator = 'on';
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.OutlineTab = uitab(app.TabGroup);
            app.OutlineTab.Title = 'Outline';
            app.ModifyPointsPanel = uipanel(app.OutlineTab);
            app.ModifyPointsPanel.Title = 'Modify points';
            app.AppendButton = uibutton(app.ModifyPointsPanel, 'state');
            app.AppendButton.ValueChangedFcn = createCallbackFcn(app, @ModifyPointsValueChanged, true);
            app.AppendButton.Text = 'Append';
            app.InsertButton = uibutton(app.ModifyPointsPanel, 'state');
            app.InsertButton.ValueChangedFcn = createCallbackFcn(app, @ModifyPointsValueChanged, true);
            app.InsertButton.Text = 'Insert';
            app.DeletePointButton = uibutton(app.ModifyPointsPanel, 'state');
            app.DeletePointButton.ValueChangedFcn = createCallbackFcn(app, @ModifyPointsValueChanged, true);
            app.DeletePointButton.Text = 'Delete';
            app.MoveButton = uibutton(app.ModifyPointsPanel, 'state');
            app.MoveButton.ValueChangedFcn = createCallbackFcn(app, @ModifyPointsValueChanged, true);
            app.MoveButton.Text = 'Move';
            app.ModifyRoadPanel = uipanel(app.OutlineTab);
            app.ActiveroadDropDownLabel = uilabel(app.ModifyRoadPanel);
            app.ActiveroadDropDownLabel.Text = 'Active road';
            app.ActiveroadDropDown = uidropdown(app.ModifyRoadPanel);
            app.ActiveroadDropDown.Items = {'New road'};
            app.ActiveroadDropDown.ItemsData = {'-1'};
            app.ActiveroadDropDown.ValueChangedFcn = createCallbackFcn(app, @ActiveroadDropDownValueChanged, true);
            app.ActiveroadDropDown.Value = '-1';
            app.DeleteRoadButton = uibutton(app.ModifyRoadPanel, 'push');
            app.DeleteRoadButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRoadButtonPushed, true);
            app.DeleteRoadButton.Text = 'Delete';
            app.BoundaryconditionsPanel = uipanel(app.ModifyRoadPanel);
            app.BoundaryconditionsPanel.Title = 'Boundary conditions';
            app.CyclicCheckBox = uicheckbox(app.BoundaryconditionsPanel);
            app.CyclicCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoundaryCheckBoxValueChanged, true);
            app.CyclicCheckBox.Text = 'Cyclic';
            app.PsiPanel = uipanel(app.BoundaryconditionsPanel);
            app.radLabel = uilabel(app.PsiPanel);
            app.radLabel.Text = 'π rad';
            app.Label_3 = uilabel(app.PsiPanel);
            app.Label_3.Text = 'Ψ₀';
            app.Psi0 = uieditfield(app.PsiPanel, 'numeric');
            app.Psi0.Limits = [-2 2];
            app.Psi0.ValueChangedFcn = createCallbackFcn(app, @PsiValueChanged, true);
            app.Label = uilabel(app.PsiPanel);
            app.Label.Text = 'Ψ₁';
            app.Psi1 = uieditfield(app.PsiPanel, 'numeric');
            app.Psi1.Limits = [-2 2];
            app.Psi1.ValueChangedFcn = createCallbackFcn(app, @PsiValueChanged, true);
            app.AutoCheckBox = uicheckbox(app.BoundaryconditionsPanel);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoundaryCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.LanelayoutTab = uitab(app.TabGroup);
            app.LanelayoutTab.Title = 'Lane layout';
            app.GlobalLayoutPanel = uipanel(app.LanelayoutTab);
            app.GlobalLayoutPanel.Title = 'Global layout';
            app.InsertLaneButton = uibutton(app.GlobalLayoutPanel, 'state');
            app.InsertLaneButton.ValueChangedFcn = createCallbackFcn(app, @LayoutChangeButtonValueChanged, true);
            app.InsertLaneButton.Text = 'Insert lane';
            app.RemoveLaneButton = uibutton(app.GlobalLayoutPanel, 'state');
            app.RemoveLaneButton.ValueChangedFcn = createCallbackFcn(app, @LayoutChangeButtonValueChanged, true);
            app.RemoveLaneButton.Text = 'Remove lane';
            app.OffsetButton = uibutton(app.GlobalLayoutPanel, 'state');
            app.OffsetButton.ValueChangedFcn = createCallbackFcn(app, @LayoutChangeButtonValueChanged, true);
            app.OffsetButton.Text = 'Offset';
            app.ConnectButton = uibutton(app.GlobalLayoutPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @LayoutChangeButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.LocalLayoutPanel = uipanel(app.LanelayoutTab);
            app.LocalLayoutPanel.Title = 'Local layout';
            app.WidthButton = uibutton(app.LocalLayoutPanel, 'state');
            app.WidthButton.ValueChangedFcn = createCallbackFcn(app, @LayoutChangeButtonValueChanged, true);
            app.WidthButton.Text = 'Width';
            app.GapButton = uibutton(app.LocalLayoutPanel, 'state');
            app.GapButton.ValueChangedFcn = createCallbackFcn(app, @LayoutChangeButtonValueChanged, true);
            app.GapButton.Text = 'Gap';
            app.PropertyButton = uibutton(app.LocalLayoutPanel, 'state');
            app.PropertyButton.ValueChangedFcn = createCallbackFcn(app, @LayoutChangeButtonValueChanged, true);
            app.PropertyButton.Text = 'Property';
            app.PropertyDropDown = uidropdown(app.LocalLayoutPanel);
            app.PropertyDropDown.Items = {'Speed', 'Height', 'Direction', 'Validity', 'Left turn', 'Right turn'};
            app.PropertyDropDown.ItemsData = {'speed', 'height', 'direction', 'validity', 'left', 'right'};
            app.PropertyDropDown.ValueChangedFcn = createCallbackFcn(app, @LayoutChangeButtonValueChanged, true);
            app.PropertyDropDown.Value = 'speed';
            app.ActivePropertyPanel = uipanel(app.LanelayoutTab);
            app.ActivePropertyPanel.Title = 'Active property transitions';
            app.AddTransitionButton = uibutton(app.ActivePropertyPanel, 'state');
            app.AddTransitionButton.ValueChangedFcn = createCallbackFcn(app, @TransitionButtonValueChanged, true);
            app.AddTransitionButton.Text = 'Add';
            app.RemoveTransitionButton = uibutton(app.ActivePropertyPanel, 'state');
            app.RemoveTransitionButton.ValueChangedFcn = createCallbackFcn(app, @TransitionButtonValueChanged, true);
            app.RemoveTransitionButton.Text = 'Remove';
            app.ChangeTransitionButton = uibutton(app.ActivePropertyPanel, 'state');
            app.ChangeTransitionButton.ValueChangedFcn = createCallbackFcn(app, @TransitionButtonValueChanged, true);
            app.ChangeTransitionButton.Text = 'Change';
            app.MoveTransitionButton = uibutton(app.ActivePropertyPanel, 'state');
            app.MoveTransitionButton.ValueChangedFcn = createCallbackFcn(app, @TransitionButtonValueChanged, true);
            app.MoveTransitionButton.Text = 'Move';
            app.TransitionAxes = uiaxes(app.GridLayout);
            title(app.TransitionAxes, 'Active property transitions')
            xlabel(app.TransitionAxes, 's')
            app.ScenarioAxes = uiaxes(app.GridLayout);
            title(app.ScenarioAxes, 'Scenario')
            xlabel(app.ScenarioAxes, 'X')
            ylabel(app.ScenarioAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scenario_designer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end